classdef Commandd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CommandButtonGroup              matlab.ui.container.ButtonGroup
        SwitchLeftButton                matlab.ui.control.ToggleButton
        LaneKeepingButton               matlab.ui.control.ToggleButton
        SwitchRightButton               matlab.ui.control.ToggleButton
        UTurnLeftButton                 matlab.ui.control.ToggleButton
        v0_egoEditFieldLabel            matlab.ui.control.Label
        v0_egoEditField                 matlab.ui.control.NumericEditField
        x0_egoEditFieldLabel            matlab.ui.control.Label
        x0_egoEditField                 matlab.ui.control.NumericEditField
        y0_egoEditFieldLabel            matlab.ui.control.Label
        y0_egoEditField                 matlab.ui.control.NumericEditField
        v_ACC_setEditFieldLabel         matlab.ui.control.Label
        v_ACC_setEditField              matlab.ui.control.NumericEditField
        v_left_lane_maxEditFieldLabel   matlab.ui.control.Label
        v_left_lane_maxEditField        matlab.ui.control.NumericEditField
        v_right_lane_maxEditFieldLabel  matlab.ui.control.Label
        v_right_lane_maxEditField       matlab.ui.control.NumericEditField
        v0_BEditFieldLabel              matlab.ui.control.Label
        v0_BEditField                   matlab.ui.control.NumericEditField
        modechooseButtonGroup           matlab.ui.container.ButtonGroup
        autonomousButton                matlab.ui.control.RadioButton
        manualButton                    matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: CommandButtonGroup
        function CommandButtonGroupSelectionChanged(app, event)
        if app.autonomousButton.Value == false   
            if app.SwitchLeftButton.Value == true
                    set_param('Studienarbeit/command_manual','value',num2str(1)); 
                    %app.SwitchLeftButton.Value = false;
%                     str = app.commandseriesTextArea.Value;
%                     str{end+1}='switch left';
%                     app.commandseriesTextArea.Value = str;
                    
                    
            elseif app.SwitchRightButton.Value == true
                    set_param('Studienarbeit/command_manual','value',num2str(2));
                    %app.SwitchRightButton.Value = false;
%                     str = app.commandseriesTextArea.Value;
%                     str{end+1}='switch right';
%                     app.commandseriesTextArea.Value = str;
                    
        elseif app.UTurnLeftButton.Value == true
            set_param('Studienarbeit/command_manual','value',num2str(3));
            end
            
            pause(0.5);
%             str = app.commandseriesTextArea.Value;
%             str{end+1}='go straight';
%             app.commandseriesTextArea.Value = str;
            app.LaneKeepingButton.Value = true;
            set_param('Studienarbeit/command_manual','value',num2str(0));
    elseif app.autonomousButton.Value == true
            set_param("Studienarbeit/manual_or_autonomous",'value',num2str(0));
            app.LaneKeepingButton.Value = true;
    end
           
        end

        % Callback function
        function RuntheSetUpButtonValueChanged(app, event)
            value = app.RuntheSetUpButton.Value;
            if value == true
                instruction;
            end
        end

        % Value changed function: v0_egoEditField
        function v0_egoEditFieldValueChanged(app, event)
            value = app.v0_egoEditField.Value;
            assignin('base','v0_ego' , value);
        end

        % Value changed function: x0_egoEditField
        function x0_egoEditFieldValueChanged(app, event)
            value = app.x0_egoEditField.Value;
            assignin('base','x0_ego' , value);
        end

        % Value changed function: y0_egoEditField
        function y0_egoEditFieldValueChanged(app, event)
            value = app.y0_egoEditField.Value;
            assignin('base','y0_ego' , value);
            if value>0
                assignin('base','initial_lane' , 1);
            elseif value<0
                assignin('base','initial_lane' , 0);
            end
        end

        % Value changed function: v_ACC_setEditField
        function v_ACC_setEditFieldValueChanged(app, event)
            value = app.v_ACC_setEditField.Value;
            assignin('base','v_set' , value);
        end

        % Value changed function: v_left_lane_maxEditField
        function v_left_lane_maxEditFieldValueChanged(app, event)
            value = app.v_left_lane_maxEditField.Value;
            assignin('base','velocity_left_lane_max' , value);
        end

        % Value changed function: v_right_lane_maxEditField
        function v_right_lane_maxEditFieldValueChanged(app, event)
            value = app.v_right_lane_maxEditField.Value;
            assignin('base','velocity_right_lane_max' , value);
        end

        % Callback function
        function canswitchlaneButtonPushed(app, event)
%             initial_velocity_ego = evalin('base', 'Cf');
%             velocity_lane_max_1 = app.v_left_lane_maxEditField.Value;
%             velocity_lane_max_2 = app.v_right_lane_maxEditField.Value;
%             velocity_vehicle_B =10;
%             velocity_ego_target_1=max(min(velocity_vehicle_B + 20*1000/3600, velocity_lane_max_1), initial_velocity_ego); % 20 km/h->200/36 m/s,
%             velocity_ego_target_2=max(min(velocity_vehicle_B + 20*1000/3600, velocity_lane_max_2), initial_velocity_ego);
%             distance_between_ego_B_initial = 20;
%             lane_width = 3.6;
%             aymax = 4;
%             aymin = -4;
%             max_ac = 2;
%             safety_distance=3;
%             Tmin1 = max(sqrt(5.77*lane_width/aymax), sqrt(-5.77*lane_width/aymin));
%             Tmin2_1 = (velocity_ego_target_1 - initial_velocity_ego)/(2/3*max_ac);
%             Tmin2_2 = (velocity_ego_target_2 - initial_velocity_ego)/(2/3*max_ac);
%             duration_1 = 2*(distance_between_ego_B_initial - safety_distance )/(velocity_ego_target_1 + initial_velocity_ego - 2*velocity_vehicle_B);
%             can_overtake_1 = max(Tmin1,Tmin2_1) < duration_1;
%             if can_overtake_1 == 1
%                 app.switch_left_yesButton.Value=true;
%             elseif can_overtake_1 == 0
%                 app.switch_left_noButton.Value = true;
%             end
%             duration_2 = 2*(distance_between_ego_B_initial - safety_distance )/(velocity_ego_target_2 + initial_velocity_ego - 2*velocity_vehicle_B);
%             can_overtake_2 = max(Tmin1,Tmin2_2) < duration_2;
%             if can_overtake_2 == 1
%                 app.switch_right_yesButton.Value=true;
%             elseif can_overtake_2 == 0
%                 app.switch_right_noButton.Value = true;
%             end
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            selectedButtonon = app.modeButtonGroup.SelectedObject;
            
        end

        % Value changed function: v0_BEditField
        function v0_BEditFieldValueChanged(app, event)
            value = app.v0_BEditField.Value;
            assignin('base','velocity_vehicle_B' , value);
        end

        % Callback function
        function modeButtonGroupSelectionChanged(app, event)
            selectedButton = app.modeButtonGroup.SelectedObject;
            
           
        end

        % Selection changed function: modechooseButtonGroup
        function modechooseButtonGroupSelectionChanged(app, event)
            selectedButton = app.modechooseButtonGroup.SelectedObject;
             if app.autonomousButton.Value == true
                set_param("Studienarbeit/manual_or_autonomous",'value',num2str(1));
              
             elseif app.manualButton.Value == true
                 set_param("Studienarbeit/manual_or_autonomous",'value',num2str(0));
                 
             end
        end

        % Callback function
        function UTurnButtonGroupSelectionChanged(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CommandButtonGroup = uibuttongroup(app.UIFigure);
            app.CommandButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CommandButtonGroupSelectionChanged, true);
            app.CommandButtonGroup.Title = 'Command';
            app.SwitchLeftButton = uitogglebutton(app.CommandButtonGroup);
            app.SwitchLeftButton.Text = 'Switch Left';
            app.LaneKeepingButton = uitogglebutton(app.CommandButtonGroup);
            app.LaneKeepingButton.Text = 'Lane Keeping';
            app.LaneKeepingButton.Value = true;
            app.SwitchRightButton = uitogglebutton(app.CommandButtonGroup);
            app.SwitchRightButton.Text = 'Switch Right';
            app.UTurnLeftButton = uitogglebutton(app.CommandButtonGroup);
            app.UTurnLeftButton.Text = 'U-Turn Left';
            app.v0_egoEditFieldLabel = uilabel(app.UIFigure);
            app.v0_egoEditFieldLabel.Text = 'v0_ego';
            app.v0_egoEditField = uieditfield(app.UIFigure, 'numeric');
            app.v0_egoEditField.ValueChangedFcn = createCallbackFcn(app, @v0_egoEditFieldValueChanged, true);
            app.v0_egoEditField.Value = 10;
            app.x0_egoEditFieldLabel = uilabel(app.UIFigure);
            app.x0_egoEditFieldLabel.Text = 'x0_ego';
            app.x0_egoEditField = uieditfield(app.UIFigure, 'numeric');
            app.x0_egoEditField.ValueChangedFcn = createCallbackFcn(app, @x0_egoEditFieldValueChanged, true);
            app.y0_egoEditFieldLabel = uilabel(app.UIFigure);
            app.y0_egoEditFieldLabel.Text = 'y0_ego';
            app.y0_egoEditField = uieditfield(app.UIFigure, 'numeric');
            app.y0_egoEditField.ValueChangedFcn = createCallbackFcn(app, @y0_egoEditFieldValueChanged, true);
            app.y0_egoEditField.Value = -2;
            app.v_ACC_setEditFieldLabel = uilabel(app.UIFigure);
            app.v_ACC_setEditFieldLabel.Text = 'v_ACC_set';
            app.v_ACC_setEditField = uieditfield(app.UIFigure, 'numeric');
            app.v_ACC_setEditField.ValueChangedFcn = createCallbackFcn(app, @v_ACC_setEditFieldValueChanged, true);
            app.v_ACC_setEditField.Value = 15;
            app.v_left_lane_maxEditFieldLabel = uilabel(app.UIFigure);
            app.v_left_lane_maxEditFieldLabel.Text = 'v_left_lane_max';
            app.v_left_lane_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.v_left_lane_maxEditField.ValueChangedFcn = createCallbackFcn(app, @v_left_lane_maxEditFieldValueChanged, true);
            app.v_left_lane_maxEditField.Value = 28;
            app.v_right_lane_maxEditFieldLabel = uilabel(app.UIFigure);
            app.v_right_lane_maxEditFieldLabel.Text = 'v_right_lane_max';
            app.v_right_lane_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.v_right_lane_maxEditField.ValueChangedFcn = createCallbackFcn(app, @v_right_lane_maxEditFieldValueChanged, true);
            app.v_right_lane_maxEditField.Value = 20;
            app.v0_BEditFieldLabel = uilabel(app.UIFigure);
            app.v0_BEditFieldLabel.Text = 'v0_B';
            app.v0_BEditField = uieditfield(app.UIFigure, 'numeric');
            app.v0_BEditField.ValueChangedFcn = createCallbackFcn(app, @v0_BEditFieldValueChanged, true);
            app.v0_BEditField.Value = 10;
            app.modechooseButtonGroup = uibuttongroup(app.UIFigure);
            app.modechooseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @modechooseButtonGroupSelectionChanged, true);
            app.modechooseButtonGroup.Title = 'mode choose';
            app.autonomousButton = uiradiobutton(app.modechooseButtonGroup);
            app.autonomousButton.Text = 'autonomous';
            app.autonomousButton.Value = true;
            app.manualButton = uiradiobutton(app.modechooseButtonGroup);
            app.manualButton.Text = 'manual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Commandd

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end